classdef app_predikcne_tunely < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        PlotGridLayout             matlab.ui.container.GridLayout
        PredikciaUIAxes            matlab.ui.control.UIAxes
        DolnaHranicaUIAxes         matlab.ui.control.UIAxes
        HornaHranicaUIAxes         matlab.ui.control.UIAxes
        TunelUIAxes                matlab.ui.control.UIAxes
        MenuGridLayout             matlab.ui.container.GridLayout
        VysledkyGridLayout         matlab.ui.container.GridLayout
        PocetLabel                 matlab.ui.control.Label
        DlzkaLabel                 matlab.ui.control.Label
        CasLabel                   matlab.ui.control.Label
        VypocitajUlozGridLayout    matlab.ui.container.GridLayout
        UlozButton                 matlab.ui.control.Button
        VypocitajButton            matlab.ui.control.Button
        SkalovanieGridLayout       matlab.ui.container.GridLayout
        OkoloUtokuCheckBox         matlab.ui.control.CheckBox
        OkoloUtokuEditField        matlab.ui.control.NumericEditField
        OdEditField                matlab.ui.control.NumericEditField
        DoEditField                matlab.ui.control.NumericEditField
        DoLabel                    matlab.ui.control.Label
        OdLabel                    matlab.ui.control.Label
        VyberVyhladenieGridLayout  matlab.ui.container.GridLayout
        VyberParametreVyhladenieGridLayout  matlab.ui.container.GridLayout
        BetaEditField              matlab.ui.control.NumericEditField
        AlfaEditField              matlab.ui.control.NumericEditField
        BEditField                 matlab.ui.control.NumericEditField
        BLabel                     matlab.ui.control.Label
        BetaLabel                  matlab.ui.control.Label
        AlfaLabel                  matlab.ui.control.Label
        VyberVyhladenieLabel       matlab.ui.control.Label
        VyberVyhladenieDropDown    matlab.ui.control.DropDown
        VyberTunelGridLayout       matlab.ui.container.GridLayout
        TunelMoznostiGridLayout    matlab.ui.container.GridLayout
        TunelMoznostiEditField     matlab.ui.control.NumericEditField
        SigmaEditField             matlab.ui.control.NumericEditField
        TunelMoznostiLabel         matlab.ui.control.Label
        SigmaLabel                 matlab.ui.control.Label
        VyberMetoduLabel           matlab.ui.control.Label
        VyberMetoduDropDown        matlab.ui.control.DropDown
        VyberParameterGridLayout   matlab.ui.container.GridLayout
        VyberParameterDropDown     matlab.ui.control.DropDown
        VyberparameterLabel        matlab.ui.control.Label
        NacitajVstupGridLayout     matlab.ui.container.GridLayout
        NacitajVstupLabel          matlab.ui.control.Label
        NacitajVstupButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        vyhladenie = 0;     % vyber vyhladenia - 0 bez, 1 exponencialne, 2 holtovo
        metoda = 1;     % vyber metody- 1 sigmaTunel, 2 polynom, 3 DFT, 4 AR(1), 5 AR(m,n)
        tunel;     % ulozi sa do nej navratova hodnota z vypoctu tunela - tunel(1,:) - horna hranica, tunel(2,:) - dolna hranica, tunel(3,:) - aproximacia
        data;       % do premennej sa ulozi konkretny parameter
        povodnyProces;  %  pri pripadnej zmene tunela (vyhladenia) sa v tejto premennej uchova povodny proces
        N;      % dlzka parametra
        casUtoku;   % specificky pre kazdy utok
        utok;  
        okno;

        % od ktore po ktory ts sa robi tunel...v tomto pripade  sa nikde
        % nemeni
        zaciatok = 1;   
        vystup = 100000;

        % skalovanie vykreslenia
        od = 1;
        do = 100000; % pri kazdom utoku sa nastavi na max dlzku

        % rozne nastavenia pri metodach, nemeni sa v podstate nikde
        dlzkaOkna = 100;      % okno z ktoreho sa predikuje
        pocetPredikovanych = 10;     % kolko budeme predikovat

        sigma = 2;      % sirka intervalu
        polynom = 2;        % stupen polynomu pri regresnom polynome
        kalibracia = 400;   % pocet ts na natrenovanie pri ar(m,n)

        % rozne parametre pre vyhladenia
        alfa = 0.05;
        beta = 0.8;
        bb = 0.5;

        % parametre
        param_entropy;
        param_hurst;
        param_std;
        param_correlation;
        param_skewness;
        param_sharpness;
        param_variability;
        param_divergence;
        param_autoregression;

        pocesFalosnychHlaseni;
        dlzkaFalosnychHlaseni;
        casRozpoznania;
        centrHore;
        centrDole;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: VyberMetoduDropDown
        function Option_vyberMetodu(app, event)

            switch app.VyberMetoduDropDown.Value
                case 'Sigma tunel'
                    app.metoda = 1;
                    app.TunelMoznostiEditField.Visible = "off";
                    app.TunelMoznostiLabel.Visible = "off";
                case 'Regresný polynóm'   
                    app.metoda = 2;
                    app.TunelMoznostiEditField.Visible = "on";
                    app.TunelMoznostiLabel.Visible = "on";
                    app.TunelMoznostiLabel.Text = 'Stupeň polynómu';
                    app.TunelMoznostiEditField.Value = 2;
                case 'DFT'
                    app.metoda = 3;
                    app.TunelMoznostiEditField.Visible = "off";
                    app.TunelMoznostiLabel.Visible = "off";
                case 'AR(n)'
                    app.metoda = 4;
                    app.TunelMoznostiEditField.Visible = "off";
                    app.TunelMoznostiLabel.Visible = "off";
                otherwise
                    app.metoda = 5;
                    app.TunelMoznostiEditField.Visible = "on";
                    app.TunelMoznostiLabel.Visible = "on";
                    app.TunelMoznostiLabel.Text = 'Kalibrácia';
                    app.TunelMoznostiEditField.Value = 400;
            end
        end

        % Clicked callback: VyberVyhladenieDropDown
        function Option_vyberVyhladenie(app, event)
            switch app.VyberVyhladenieDropDown.Value
                case 'Žiadne'
                    app.vyhladenie = 0;
                    app.VyberParametreVyhladenieGridLayout.Visible = 'off';
                case 'Exponenciálne'
                    app.vyhladenie = 1;
                    app.AlfaEditField.Visible = "on";
                    app.AlfaLabel.Visible = "on";
                    app.BetaEditField.Visible = 'off';
                    app.BetaLabel.Visible = 'off';
                    app.BEditField.Visible = 'off';
                    app.BLabel.Visible = 'off';
                    app.VyberParametreVyhladenieGridLayout.Visible = 'on';
                case 'Holt'
                    app.vyhladenie = 2;
                    app.VyberParametreVyhladenieGridLayout.Visible = 'on';
                    app.BetaEditField.Visible = 'on';
                    app.BetaLabel.Visible = 'on';
                    app.BEditField.Visible = 'on';
                    app.BLabel.Visible = 'on';
            end
        end

        % Button pushed function: VypocitajButton
        function VypocitajFunction(app, event)
            % pre istotu keby sa pouzivatel pomylil a zaskrtol by chceckbox
            % pre vykreslenie okolo tunela a zaroven
            % by nastavil rozsah vykreslenia...
            value = app.OkoloUtokuCheckBox.Value;
            if value == 1
                app.od = app.casUtoku - app.OkoloUtokuEditField.Value;
                app.do = app.casUtoku + app.OkoloUtokuEditField.Value;
            else
                app.do = app.DoEditField.Value;
                app.od = app.OdEditField.Value;
                if app.od > app.do - 100
                    app.od = app.do - 100;
                end
            end


            % kontrola vstupu
            app.vystup = app.N - 1;

            if app.do > app.N
                app.do = app.N - 1;
            end

            if app.od > (app.N)
                app.od = app.N - 1;
            end
            
            app.do = app.do - 1;

            % vykonanie vyhladenia
            switch app.vyhladenie
                case 1
                    app.data = exponencialne_vyhladenie(app.data, app.alfa);
                case 2
                    app.data = modif_holtovo_vyhladenie(app.data, app.alfa, app.beta, app.bb);
                otherwise
                    app.data = app.povodnyProces;
            end
            
            % vytvorenie tunela
            switch app.metoda 
                case 1
                    app.tunel = dve_sigmy(app.data,app.dlzkaOkna,app.zaciatok,app.vystup,app.sigma);
                case 2
                    app.tunel = regresny_polynom(app.data,app.dlzkaOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,app.polynom,app.sigma);
                case 3
                    app.tunel = fourierova_transformacia(app.data,app.dlzkaOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,0,app.sigma);
                case 4
                    app.tunel = autoregresia(app.data,app.dlzkaOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,app.sigma);
                otherwise
                    app.tunel = modifikovana_autoregresia(app.data,app.dlzkaOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,app.kalibracia,app.sigma);
            end

            % Vypocet dolnej a hornej hranice
            % hh
            if app.metoda == 5 
                app.centrHore = app.povodnyProces(app.kalibracia +app.dlzkaOkna+ 1: app.kalibracia+ app.vystup- app.kalibracia) - app.tunel(1,:);
            else
                app.centrHore = app.povodnyProces(app.dlzkaOkna + 1: app.dlzkaOkna + app.vystup - app.dlzkaOkna) - app.tunel(1,:);
            end
            % dh
            if app.metoda == 5 
                app.centrDole = app.povodnyProces(app.kalibracia +app.dlzkaOkna+ 1: app.kalibracia+ app.vystup- app.kalibracia) - app.tunel(2,:);
            else
                app.centrDole = app.povodnyProces(app.dlzkaOkna + 1: app.dlzkaOkna + app.vystup - app.dlzkaOkna) - app.tunel(2,:);
            end

            % VYPOCET EFEKTIVITY

            % pocet falosnych utokov
            pocetFalosnych = 0;
            
            if app.metoda == 5
                for t = 2:app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaOkna)
                    if app.centrHore(t) > 0 && app.centrHore(t-1) <= 0
                        pocetFalosnych = pocetFalosnych + 1;
                    end
                    if app.centrDole(t) < 0 && app.centrDole(t-1) >= 0
                        pocetFalosnych = pocetFalosnych + 1;
                    end
                end
            else
                for t = 2:app.casUtoku-(app.zaciatok+app.dlzkaOkna)
                    if app.centrHore(t) > 0 && app.centrHore(t-1) <= 0
                        pocetFalosnych = pocetFalosnych + 1;
                    end
                    if app.centrDole(t) < 0 && app.centrDole(t-1) >= 0
                        pocetFalosnych = pocetFalosnych + 1;
                    end
                end
            end
            app.pocesFalosnychHlaseni = pocetFalosnych;
            

            % dlzka falosnych hlaseni
            pocetFalosnychSlotov = 0;
            if app.metoda == 5
                for t = 2:app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaOkna)
                    if app.centrHore(t) > 0
                        pocetFalosnychSlotov = pocetFalosnychSlotov + 1;            
                    end
                    if app.centrDole(t) < 0
                        pocetFalosnychSlotov = pocetFalosnychSlotov + 1;
                    end
                end
            else
                for t = 2:app.casUtoku-(app.zaciatok+app.dlzkaOkna)
                    if app.centrHore(t) > 0
                        pocetFalosnychSlotov = pocetFalosnychSlotov + 1;
                    end
                    if app.centrDole(t) < 0
                        pocetFalosnychSlotov = pocetFalosnychSlotov + 1;
                    end
                end
            end
            app.dlzkaFalosnychHlaseni = pocetFalosnychSlotov;
            

            % cas rozpoznania od zaciatku utoku
            casHore = 1;
            casDole = 1;
            if app.metoda == 5
                t = app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaOkna);
            else
                t = app.casUtoku-(app.zaciatok+app.dlzkaOkna);
            end    
            

            while app.centrHore(t) > 0 && app.centrHore(t-1) > 0
                casHore = casHore + 1;
                t = t+1;
            end
          

            while app.centrHore(t) < 0
                casHore = casHore + 1;
                if t < length(app.centrHore)
                    t = t + 1;
                else
                    break;
                end
            end
            
            if app.metoda == 5
                t = app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaOkna)+1;
            else
                t = app.casUtoku-(app.zaciatok+app.dlzkaOkna)+1;
            end 
            
            while app.centrDole(t) < 0 && app.centrDole(t-1) < 0
                casDole = casDole + 1;
                t = t + 1;
            end
            
            while app.centrDole(t) > 0
                casDole = casDole + 1;
                if t < length(app.centrDole)
                    t = t + 1;
                else
                    break;
                end
            end
            app.casRozpoznania = min(casDole,casHore);

                  
            app.PocetLabel.Text = ['Počet falošných hlásení: ',num2str(app.pocesFalosnychHlaseni)];
            app.DlzkaLabel.Text = ['Dĺžka faošných hlásení: ',num2str(app.dlzkaFalosnychHlaseni)];
            app.CasLabel.Text = ['Doba rozpoznania: ',num2str(app.casRozpoznania)];

            % PLOT
            timeVystup = [app.od:app.do];
            povodnyVykreslenie = app.povodnyProces(app.od:app.do);
            
            if app.metoda == 5
                timeTunel = [app.od+app.dlzkaOkna+app.kalibracia:app.do];
                tunelVykreslenie = app.tunel(:,app.od:app.do-app.dlzkaOkna-app.kalibracia);
            else
                timeTunel = [app.od+app.dlzkaOkna:app.do];
                tunelVykreslenie = app.tunel(:,app.od:app.do-app.dlzkaOkna);
            end

             % if - ak sa vykresli aj ciara utoku
             % else - ak je rozsah vykreslenia mimo utoku a teda ciara sa
             % nevykresli
            if app.casUtoku > app.od && app.casUtoku < app.do 
                % plot tunel
                plot(app.TunelUIAxes,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(1,:),'k',timeTunel,tunelVykreslenie(2,:),'k', [app.od+app.dlzkaOkna app.od+app.dlzkaOkna],[min(tunelVykreslenie(2,:)) max(tunelVykreslenie(1,:))], 'black--', [app.casUtoku app.casUtoku],[min(tunelVykreslenie(2,:)) max(tunelVykreslenie(1,:))], 'black--')
                xlim(app.TunelUIAxes, [min(timeVystup) max(timeVystup)]); 
               
                % plot predikcia
                if app.metoda ~= 1
                    plot(app.PredikciaUIAxes,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(3,:),'r--',[app.casUtoku app.casUtoku],[min(povodnyVykreslenie) max(povodnyVykreslenie)], 'black--')
                else 
                    plot(app.PredikciaUIAxes,0,0) % pretoze sigma tunel nema aproximaxciu nakolko to nie je predikcia...
                end
                xlim(app.PredikciaUIAxes, [min(timeVystup) max(timeVystup)]);

                switch app.metoda 
                    case 1
                        app.TunelUIAxes.Title.String = 'sigma tunel';     
                    case 2
                        app.TunelUIAxes.Title.String = 'Regresny tunel';
                    case 3
                        app.TunelUIAxes.Title.String = 'DFT tunel';
                    case 4
                        app.TunelUIAxes.Title.String = 'AR(1) tunel';
                    otherwise
                        app.TunelUIAxes.Title.String = 'AR(m,n) tunel';
                end
    
                % plot horna hranica
                if app.metoda == 5
                    plot(app.HornaHranicaUIAxes,timeTunel,app.centrHore(app.od:app.do-app.dlzkaOkna-app.kalibracia),timeVystup,0 * [app.od:app.do], 'black',[app.casUtoku app.casUtoku], [min(app.centrHore) max(app.centrHore)], 'black--')
                else
                    plot(app.HornaHranicaUIAxes,timeTunel,app.centrHore(app.od:app.do-app.dlzkaOkna),timeVystup,0 * [app.od:app.do], 'black',[app.casUtoku app.casUtoku],[min(app.centrHore) max(app.centrHore)], 'black--')
                end
                xlim(app.HornaHranicaUIAxes, [min(timeVystup) max(timeVystup)]);
    
                % plot dolna hranica
                if app.metoda == 5
                    plot(app.DolnaHranicaUIAxes,timeTunel,app.centrDole(app.od:app.do-app.dlzkaOkna-app.kalibracia),timeVystup, 0 * [app.od:app.do], 'black',[app.casUtoku app.casUtoku],[min(app.centrDole) max(app.centrDole)], 'black--')
                else
                    plot(app.DolnaHranicaUIAxes,timeTunel,app.centrDole(app.od:app.do-app.dlzkaOkna),timeVystup, 0 * [app.od:app.do], 'black',[app.casUtoku app.casUtoku],[min(app.centrDole) max(app.centrDole)], 'black--')
                end
                xlim(app.DolnaHranicaUIAxes, [min(timeVystup) max(timeVystup)]);

            else
                % plot tunel
                plot(app.TunelUIAxes,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(1,:),'k',timeTunel,tunelVykreslenie(2,:),'k',[app.od+app.dlzkaOkna app.od+app.dlzkaOkna],[min(tunelVykreslenie(2,:)) max(tunelVykreslenie(1,:))], 'black--')
                xlim(app.TunelUIAxes, [min(timeVystup) max(timeVystup)]);       
    
                % plot predikcia
                if app.metoda ~= 1
                    plot(app.PredikciaUIAxes,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(3,:),'r--')
                else 
                    plot(app.PredikciaUIAxes,0,0) % pretoze sigma tunel nema aproximaxciu nakolko to nie je predikcia...
                end
                xlim(app.PredikciaUIAxes, [min(timeVystup) max(timeVystup)]);

                switch app.metoda 
                    case 1
                        app.TunelUIAxes.Title.String = 'sigma tunel';     
                    case 2
                        app.TunelUIAxes.Title.String = 'Regresny tunel';
                    case 3
                        app.TunelUIAxes.Title.String = 'DFT tunel';
                    case 4
                        app.TunelUIAxes.Title.String = 'AR(1) tunel';
                    otherwise
                        app.TunelUIAxes.Title.String = 'AR(m,n) tunel';
                end
    
                % plot horna hranica
                if app.metoda == 5
                    plot(app.HornaHranicaUIAxes,timeTunel,app.centrHore(app.od:app.do-app.dlzkaOkna-app.kalibracia),timeVystup,0 * [app.od:app.do], 'black')
                else
                    plot(app.HornaHranicaUIAxes,timeTunel,app.centrHore(app.od:app.do-app.dlzkaOkna),timeVystup,0 * [app.od:app.do], 'black')
                end
                xlim(app.HornaHranicaUIAxes, [min(timeVystup) max(timeVystup)]);
    
                % plot dolna hranica
                if app.metoda == 5
                    plot(app.DolnaHranicaUIAxes,timeTunel,app.centrDole(app.od:app.do-app.dlzkaOkna-app.kalibracia),timeVystup, 0 * [app.od:app.do], 'black')
                else
                    plot(app.DolnaHranicaUIAxes,timeTunel,app.centrDole(app.od:app.do-app.dlzkaOkna),timeVystup, 0 * [app.od:app.do], 'black')
                end
                xlim(app.DolnaHranicaUIAxes, [min(timeVystup) max(timeVystup)]);
            end
        end

        % Value changed function: SigmaEditField
        function nastavSigmy(app, event)
            app.sigma = app.SigmaEditField.Value;
        end

        % Value changed function: AlfaEditField
        function nastavAlfu(app, event)
            app.alfa = app.AlfaEditField.Value;            
        end

        % Value changed function: BetaEditField
        function nastavBetu(app, event)
            app.beta = app.BetaEditField.Value;
        end

        % Value changed function: BEditField
        function nastavB(app, event)
            app.bb = app.BEditField.Value;            
        end

        % Value changed function: TunelMoznostiEditField
        function nastavMoznostiTunelu(app, event)
            if app.TunelMoznostiLabel.Text == "Stupeň polynómu"
                app.polynom = app.TunelMoznostiEditField.Value;  
            else 
                app.kalibracia = app.TunelMoznostiEditField.Value;  
            end
        end

        % Button pushed function: NacitajVstupButton
        function NacitajData(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select a .mat file');
            if isequal(filename,0) || isequal(pathname,0)
                % User cancelled the file selection dialog
                        message = {'Nebol načítaný žiadny súbor!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                return;
            end
            load(fullfile(pathname, filename));
  
            app.param_entropy = E;
            app.param_hurst = H;
            app.param_std = s;
            app.param_skewness = Skw;
            app.param_variability = V;
            app.param_autoregression = c;
            app.param_correlation = ro;
            app.param_divergence = divg;
            app.param_sharpness = K;

            app.utok = str2num(filename(8));
            app.okno = str2num(filename(16:19));
            
            if app.utok == 1
                app.casUtoku = 10000;
            elseif app.utok == 2 
                app.casUtoku = 7823;
            elseif app.utok == 3
                app.casUtoku = 5244;          
            elseif app.utok == 4 
                app.casUtoku = 3060;
            elseif app.utok == 6 
                app.casUtoku = 7830;
            else
                app.casUtoku = 5261; 
            end  

            app.casUtoku = app.casUtoku - app.okno;

            % v pripade ze by pouzivatel nenastavil ziaden parameter
            app.data = app.param_entropy; 
            app.N = length(app.data);
            app.povodnyProces = app.data;
            app.DoEditField.Value = app.N;

            app.NacitajVstupLabel.Text = ['Útok_',num2str(app.utok),'_',num2str(app.okno)];

          
        end

        % Clicked callback: VyberParameterDropDown
        function Option_vyberParameter(app, event)
            parameter = app.VyberParameterDropDown.Value;
                                    % podla vyberu vypoctu parametra sa do premennej data ulozi parameter
            switch parameter
                case 'Entropia'
                    app.data = app.param_entropy;
                case 'Smerodajná odchýlka'
                    app.data = app.param_std;
                case 'Variancia'
                    app.data = app.param_variability;
                case 'Špicatosť'
                    app.data = app.param_sharpness;
                case 'Šikmosť'
                    app.data = app.param_skewness;
                case 'Hurstov exponent'
                    app.data = app.param_hurst;
                case 'Autoregresia'
                    app.data = app.param_autoregression;
                case 'Koeficient korelácie'
                    app.data = app.param_correlation;
                otherwise
                    app.data = app.param_divergence;
            end     

            app.N = length(app.data);
            app.povodnyProces = app.data;
            app.DoEditField.Value = app.N;


        end

        % Value changed function: OdEditField
        function nastavOd(app, event)
            app.od = app.OdEditField.Value;
            if app.od > app.do - 100
                app.od = app.do - 100;
            end
        end

        % Value changed function: DoEditField
        function nastavDo(app, event)
            app.do = app.DoEditField.Value;
        end

        % Button pushed function: UlozButton
        function UlozFunction(app, event)

            [filename, pathname] = uiputfile('*.fig', 'Save Figure As');
            if isequal(filename,0) || isequal(pathname,0)
                    message = {'Nebol uložený žiadny súbor!'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                return;
            end
            [~, ~, ext] = fileparts(filename);
            if strcmp(ext,'.fig')
                % Save the figure as a .fig file
                h = figure('Visible', 'on');
                ax = copyobj(app.TunelUIAxes, h);
                set(ax, 'Units', 'normalized', 'Position', [0.05 0.05 0.9 0.9]);
                savefig(h, fullfile(pathname, filename));
                delete(h);
            elseif strcmp(ext,'.png')
                % Save the figure as a .png file
                f = getframe(app.TunelUIAxes);
                imwrite(f.cdata, fullfile(pathname, filename), 'png');
            else
                % Unsupported file format
                return;
            end
            % Update the UI after the figure has been saved, if necessary
            uialert(app.UIFigure, 'Úspešne uložené!', 'Saved', 'Icon','success');


        end

        % Value changed function: OkoloUtokuEditField
        function okoloUtokuFunction(app, event)
            okolo = app.OkoloUtokuEditField.Value;
            if okolo < 200
                okolo = 200;
            end

            if okolo > app.N / 2 - 1
                okolo = app.N / 2 - 1;
            end
            app.od = app.casUtoku - okolo;
            app.do = app.casUtoku + okolo;
        end

        % Value changed function: OkoloUtokuCheckBox
        function okoloUtokuVolanieFunction(app, event)
            value = app.OkoloUtokuCheckBox.Value;
            if value == 1
                app.od = app.casUtoku - app.OkoloUtokuEditField.Value;
                app.do = app.casUtoku + app.OkoloUtokuEditField.Value;
            else
                app.do = app.DoEditField.Value;
                app.od = app.OdEditField.Value;
                if app.od > app.do - 100
                    app.od = app.do - 100;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MenuGridLayout = uigridlayout(app.GridLayout);
            app.NacitajVstupGridLayout = uigridlayout(app.MenuGridLayout);
            app.NacitajVstupButton = uibutton(app.NacitajVstupGridLayout, 'push');
            app.NacitajVstupButton.ButtonPushedFcn = createCallbackFcn(app, @NacitajData, true);
            app.NacitajVstupButton.Text = 'Načítať vstup';
            app.NacitajVstupLabel = uilabel(app.NacitajVstupGridLayout);
            app.NacitajVstupLabel.Text = 'Načítaný súbor';
            app.VyberParameterGridLayout = uigridlayout(app.MenuGridLayout);
            app.VyberparameterLabel = uilabel(app.VyberParameterGridLayout);
            app.VyberparameterLabel.Text = 'Vyber parameter';
            app.VyberParameterDropDown = uidropdown(app.VyberParameterGridLayout);
            app.VyberParameterDropDown.Items = {'Entropia', 'Šikmosť', 'Špicatosť', 'Smerodajná odchýlka', 'Hurstov exponent', 'Divergencia', 'Koeficient korelácie', 'Autoregresia', 'Variancia'};
            app.VyberParameterDropDown.ClickedFcn = createCallbackFcn(app, @Option_vyberParameter, true);
            app.VyberParameterDropDown.Value = 'Šikmosť';
            app.VyberTunelGridLayout = uigridlayout(app.MenuGridLayout);
            app.VyberMetoduDropDown = uidropdown(app.VyberTunelGridLayout);
            app.VyberMetoduDropDown.Items = {'Sigma tunel', 'Regresný polynóm', 'DFT', 'AR(n)', 'AR(n,m)'};
            app.VyberMetoduDropDown.ClickedFcn = createCallbackFcn(app, @Option_vyberMetodu, true);
            app.VyberMetoduDropDown.Value = 'Sigma tunel';
            app.VyberMetoduLabel = uilabel(app.VyberTunelGridLayout);
            app.VyberMetoduLabel.Text = 'Vyber tunel';
            app.TunelMoznostiGridLayout = uigridlayout(app.VyberTunelGridLayout);
            app.SigmaLabel = uilabel(app.TunelMoznostiGridLayout);
            app.SigmaLabel.Text = 'Počet sigiem';
            app.TunelMoznostiLabel = uilabel(app.TunelMoznostiGridLayout);
            app.TunelMoznostiLabel.Visible = 'off';
            app.TunelMoznostiLabel.Text = 'Stupeň polynómu';
            app.SigmaEditField = uieditfield(app.TunelMoznostiGridLayout, 'numeric');
            app.SigmaEditField.Limits = [1 100];
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @nastavSigmy, true);
            app.SigmaEditField.Value = 2;
            app.TunelMoznostiEditField = uieditfield(app.TunelMoznostiGridLayout, 'numeric');
            app.TunelMoznostiEditField.Limits = [1 1000];
            app.TunelMoznostiEditField.RoundFractionalValues = 'on';
            app.TunelMoznostiEditField.ValueChangedFcn = createCallbackFcn(app, @nastavMoznostiTunelu, true);
            app.TunelMoznostiEditField.Visible = 'off';
            app.TunelMoznostiEditField.Value = 2;
            app.VyberVyhladenieGridLayout = uigridlayout(app.MenuGridLayout);
            app.VyberVyhladenieDropDown = uidropdown(app.VyberVyhladenieGridLayout);
            app.VyberVyhladenieDropDown.Items = {'Žiadne', 'Exponenciálne', 'Holt'};
            app.VyberVyhladenieDropDown.ClickedFcn = createCallbackFcn(app, @Option_vyberVyhladenie, true);
            app.VyberVyhladenieDropDown.Value = 'Žiadne';
            app.VyberVyhladenieLabel = uilabel(app.VyberVyhladenieGridLayout);
            app.VyberVyhladenieLabel.Text = 'Vyber vyhladenie';
            app.VyberParametreVyhladenieGridLayout = uigridlayout(app.VyberVyhladenieGridLayout);
            app.AlfaLabel = uilabel(app.VyberParametreVyhladenieGridLayout);
            app.AlfaLabel.Visible = 'off';
            app.AlfaLabel.Text = 'Alfa';
            app.BetaLabel = uilabel(app.VyberParametreVyhladenieGridLayout);
            app.BetaLabel.Visible = 'off';
            app.BetaLabel.Text = 'Beta';
            app.BLabel = uilabel(app.VyberParametreVyhladenieGridLayout);
            app.BLabel.Visible = 'off';
            app.BLabel.Text = 'b';
            app.BEditField = uieditfield(app.VyberParametreVyhladenieGridLayout, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @nastavB, true);
            app.BEditField.Visible = 'off';
            app.BEditField.Value = 0.5;
            app.AlfaEditField = uieditfield(app.VyberParametreVyhladenieGridLayout, 'numeric');
            app.AlfaEditField.ValueChangedFcn = createCallbackFcn(app, @nastavAlfu, true);
            app.AlfaEditField.Visible = 'off';
            app.AlfaEditField.Value = 0.05;
            app.BetaEditField = uieditfield(app.VyberParametreVyhladenieGridLayout, 'numeric');
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @nastavBetu, true);
            app.BetaEditField.Visible = 'off';
            app.BetaEditField.Value = 0.8;
            app.SkalovanieGridLayout = uigridlayout(app.MenuGridLayout);
            app.OdLabel = uilabel(app.SkalovanieGridLayout);
            app.OdLabel.Text = 'Od';
            app.DoLabel = uilabel(app.SkalovanieGridLayout);
            app.DoLabel.Text = 'Do';
            app.DoEditField = uieditfield(app.SkalovanieGridLayout, 'numeric');
            app.DoEditField.Limits = [1 100000];
            app.DoEditField.ValueChangedFcn = createCallbackFcn(app, @nastavDo, true);
            app.DoEditField.Value = 100000;
            app.OdEditField = uieditfield(app.SkalovanieGridLayout, 'numeric');
            app.OdEditField.Limits = [1 100000];
            app.OdEditField.ValueChangedFcn = createCallbackFcn(app, @nastavOd, true);
            app.OdEditField.Value = 1;
            app.OkoloUtokuEditField = uieditfield(app.SkalovanieGridLayout, 'numeric');
            app.OkoloUtokuEditField.Limits = [1 10000];
            app.OkoloUtokuEditField.ValueChangedFcn = createCallbackFcn(app, @okoloUtokuFunction, true);
            app.OkoloUtokuEditField.Value = 1000;
            app.OkoloUtokuCheckBox = uicheckbox(app.SkalovanieGridLayout);
            app.OkoloUtokuCheckBox.ValueChangedFcn = createCallbackFcn(app, @okoloUtokuVolanieFunction, true);
            app.OkoloUtokuCheckBox.Text = 'Rozsah okolo útoku:';
            app.VypocitajUlozGridLayout = uigridlayout(app.MenuGridLayout);
            app.VypocitajButton = uibutton(app.VypocitajUlozGridLayout, 'push');
            app.VypocitajButton.ButtonPushedFcn = createCallbackFcn(app, @VypocitajFunction, true);
            app.VypocitajButton.Text = 'Vypočítaj';
            app.UlozButton = uibutton(app.VypocitajUlozGridLayout, 'push');
            app.UlozButton.ButtonPushedFcn = createCallbackFcn(app, @UlozFunction, true);
            app.UlozButton.Text = 'Ulož';
            app.VysledkyGridLayout = uigridlayout(app.MenuGridLayout);
            app.CasLabel = uilabel(app.VysledkyGridLayout);
            app.CasLabel.Text = 'Doba rozpoznania: ';
            app.DlzkaLabel = uilabel(app.VysledkyGridLayout);
            app.DlzkaLabel.Text = 'Dĺžka faošných hlásení: ';
            app.PocetLabel = uilabel(app.VysledkyGridLayout);
            app.PocetLabel.Text = 'Počet falošných útokov: ';
            app.PlotGridLayout = uigridlayout(app.GridLayout);
            app.TunelUIAxes = uiaxes(app.PlotGridLayout);
            title(app.TunelUIAxes, 'Tunel')
            xlabel(app.TunelUIAxes, 'ts')
            zlabel(app.TunelUIAxes, 'Z')
            app.HornaHranicaUIAxes = uiaxes(app.PlotGridLayout);
            title(app.HornaHranicaUIAxes, 'Horná hranica')
            xlabel(app.HornaHranicaUIAxes, 'ts')
            zlabel(app.HornaHranicaUIAxes, 'Z')
            app.DolnaHranicaUIAxes = uiaxes(app.PlotGridLayout);
            title(app.DolnaHranicaUIAxes, 'Dolná hranica')
            xlabel(app.DolnaHranicaUIAxes, 'ts')
            zlabel(app.DolnaHranicaUIAxes, 'Z')
            app.PredikciaUIAxes = uiaxes(app.PlotGridLayout);
            title(app.PredikciaUIAxes, 'Predikcia')
            xlabel(app.PredikciaUIAxes, 'ts')
            zlabel(app.PredikciaUIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_predikcne_tunely

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end